classdef LEMT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        PathModeActivateCheckBox   matlab.ui.control.CheckBox
        TabGroup                   matlab.ui.container.TabGroup
        TLETab                     matlab.ui.container.Tab
        EndDateDatePicker          matlab.ui.control.DatePicker
        EndDateDatePickerLabel     matlab.ui.control.Label
        StartDateDatePicker        matlab.ui.control.DatePicker
        StartDateDatePickerLabel   matlab.ui.control.Label
        Tab                        matlab.ui.container.Tab
        TruePathEditField          matlab.ui.control.EditField
        TruePathEditFieldLabel     matlab.ui.control.Label
        TLEPathEditField           matlab.ui.control.EditField
        TLEPathEditFieldLabel      matlab.ui.control.Label
        AltitudeTextArea           matlab.ui.control.TextArea
        AltitudeTextAreaLabel      matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        LEOErrorModelingToolLabel  matlab.ui.control.Label
        NORADIDTextArea            matlab.ui.control.TextArea
        NORADIDLabel               matlab.ui.control.Label
        CalculateErrorButton       matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateErrorButton
        function CalculateErrorButtonPushed(app, event)
            % 체크박스 값 읽어오기 (경로지정 모드 활성화용)
            DebugModeValue = app.PathModeActivateCheckBox.Value;

            %NORAD ID 읽기
            NORADID = app.NORADIDTextArea.Value;
            NORADID = strtrim(NORADID{1});
            
            if length(NORADID) ~= 5 || ~all(isstrprop(NORADID, 'digit'))
                uialert(app.UIFigure, 'NORAD ID는 정확히 5자리 숫자로 입력해야 합니다.', '입력 오류');
                return;
            end
            
            NORADID = str2double(NORADID);

            if DebugModeValue
                %기준 데이터 경로 지정 (디버깅용)
                tle_filename = strtrim(app.TLEPathEditField.Value);
                
                %TLE 파일 경로 지정 (디버깅용)
                nasa = strtrim(app.TruePathEditField.Value);
            else
                %날짜 읽기
                startDate = app.StartDateDatePicker.Value;
                endDate = app.EndDateDatePicker.Value;
        
        
                %날짜 유효성 검사
                if ismissing(startDate) || ismissing(endDate) || isnat(startDate) || isnat(endDate)
                    uialert(app.UIFigure, '시작 날짜와 종료 날짜를 모두 선택해야 합니다.', '입력 오류');
                    return;
                end

                if startDate > endDate
                    uialert(app.UIFigure, '시작 날짜는 종료 날짜보다 이전이어야 합니다.', '입력 오류');
                    return;
                end

                fetch_and_save_TLE(NORADID, startDate, endDate);
                tle_filename = 'tle_data/satellite_state_vectors.csv';
            end
            

            % 고도 읽기 및 유효성 검사
            AltitudeR = strtrim(app.AltitudeTextArea.Value);
            if isempty(AltitudeR)
                uialert(app.UIFigure, '입력값이 비어 있습니다.', '입력 오류');
                return;
            end

            altitudeS = str2double(AltitudeR);
            if isnan(altitudeS) || mod(altitudeS, 1) ~= 0
                uialert(app.UIFigure, '정수를 입력해 주세요.', '입력 오류');
                return
            end


            %TLE와 기준 데이터로부터 오차계
            
            if NORADID == 39634
                [r, navPos, delta_O] = cal_error_sentinel(tle_filename, nasa);
            else
                [r, navPos, delta_O, O_error_RTN] = cal_error_grace(tle_filename,nasa);
            end
            
            
            [gridURE_means, gridmean_URE, gridmax_URE, gridstd_URE, pd] = get_grid_URE(r, navPos, altitudeS, delta_O);
            msg = sprintf("Grid 평균 URE: %.3f m\n최대 URE: %.3f m\n표준편차: %.3f m", ...
              gridmean_URE, gridmax_URE, gridstd_URE);
            uialert(app.UIFigure, msg, 'Grid URE 결과', 'Icon', 'info');

            [gwoure_wl_all, max_oure, gwrms_oure]=get_worst_URE(r, navPos, delta_O);
            msg = sprintf("Worst-case OURE_wl: %.3f m\n평균 URE: %.3f m\n표준편차: %.3f m\n", ...
              max_oure, gwrms_oure, std(gwoure_wl_all));
            uialert(app.UIFigure, msg, 'Worst case URE 결과', 'Icon', 'info');

            if NORADID ~= 39634
                [Wresult_clean]=weightCal(altitudeS);
                msg = sprintf("가중치 평균 URE: %.3f m\n최대 URE: %.3f m\n표준편차: %.3f m", ...
                    mean(Wresult_clean), max(Wresult_clean), std(Wresult_clean));
                uialert(app.UIFigure, msg, '가중치 URE 결과', 'Icon', 'info');
            end

            if NORADID == 39634
                [sigma_over2]=sum_graph(gridURE_means, gwoure_wl_all, [], NORADID);
            else
                [sigma_over2]=sum_graph(gridURE_means, gwoure_wl_all, Wresult_clean, NORADID);
            end
            
            %sigma_over2 = evalin('base', 'sigma_over2');
            msg = sprintf("Worst-case Overbounding Gaussian sigma_wl: %.3f m\nWorst-case URA: %.3f m\n", ...
              sigma_over2, sigma_over2*4.42);
            uialert(app.UIFigure, msg, 'Worst case URA 결과', 'Icon', 'info');
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateErrorButton = uibutton(app.UIFigure, 'push');
            app.CalculateErrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateErrorButtonPushed, true);
            app.CalculateErrorButton.Text = 'Calculate Error';
            app.NORADIDLabel = uilabel(app.UIFigure);
            app.NORADIDLabel.Text = 'NORAD ID*';
            app.NORADIDTextArea = uitextarea(app.UIFigure);
            app.LEOErrorModelingToolLabel = uilabel(app.UIFigure);
            app.LEOErrorModelingToolLabel.Text = 'LEO Error Modeling Tool';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '*위성 고유 번호. 5개의 숫자로 구성';
            app.AltitudeTextAreaLabel = uilabel(app.UIFigure);
            app.AltitudeTextAreaLabel.Text = 'Altitude';
            app.AltitudeTextArea = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TLETab = uitab(app.TabGroup);
            app.TLETab.Title = 'TLE 생성';
            app.StartDateDatePickerLabel = uilabel(app.TLETab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.TLETab);
            app.EndDateDatePickerLabel = uilabel(app.TLETab);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.TLETab);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '경로지정';
            app.TLEPathEditFieldLabel = uilabel(app.Tab);
            app.TLEPathEditFieldLabel.Text = 'TLE Path';
            app.TLEPathEditField = uieditfield(app.Tab, 'text');
            app.TruePathEditFieldLabel = uilabel(app.Tab);
            app.TruePathEditFieldLabel.Text = 'True Path';
            app.TruePathEditField = uieditfield(app.Tab, 'text');
            app.PathModeActivateCheckBox = uicheckbox(app.UIFigure);
            app.PathModeActivateCheckBox.Text = 'Path Mode Activate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'mark.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LEMT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end